classdef GmshGridParsing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeshInfUIFigure        matlab.ui.Figure
        FilesMenu              matlab.ui.container.Menu
        OpenFileMenu           matlab.ui.container.Menu
        ViewMenu               matlab.ui.container.Menu
        ShowMeshMenu           matlab.ui.container.Menu
        MainPage               matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
        GridLayout3            matlab.ui.container.GridLayout
        GridLayout5            matlab.ui.container.GridLayout
        TabGroup2              matlab.ui.container.TabGroup
        Tab_2                  matlab.ui.container.Tab
        GridLayout6            matlab.ui.container.GridLayout
        OutputInfText          matlab.ui.control.TextArea
        UIAxes                 matlab.ui.control.UIAxes
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        GridLayout4            matlab.ui.container.GridLayout
        OutputMeshInfBtn       matlab.ui.control.Button
        ShowMeshBtn            matlab.ui.control.Button
        ExtractMeshInfBtn      matlab.ui.control.Button
        OpenFileBtn            matlab.ui.control.Button
        Tab_3                  matlab.ui.container.Tab
        GridLayout7            matlab.ui.container.GridLayout
        ShowSelectedMesh       matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Label_4                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        NumOfS                 matlab.ui.control.EditField
        NumOfL                 matlab.ui.control.EditField
        NumOfN                 matlab.ui.control.EditField
        Tab_5                  matlab.ui.container.Tab
        GridLayout8            matlab.ui.container.GridLayout
        ShowSelectedMesh_3     matlab.ui.control.Button
        ShowSelectedMeshGroup  matlab.ui.control.Button
        IdOfSGroup             matlab.ui.control.EditField
        Label_7                matlab.ui.control.Label
        IdOfLGroup             matlab.ui.control.EditField
        Label_6                matlab.ui.control.Label
        GridLayout2            matlab.ui.container.GridLayout
        SelectedFile           matlab.ui.control.TextArea
        Label                  matlab.ui.control.Label
    end

    properties (Access = public)
        file = "";
        path = "";
        FullFile = "";
        MeshInf = [];
        msgbox_style = struct('WindowStyle','modal', 'Interpreter', 'tex');
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: OpenFileBtn
        function OpenFileBtnPushed(app, event)
            [app.file,app.path] = uigetfile('*.msh');
            app.FullFile = fullfile(app.path, app.file);
            app.SelectedFile.Value = app.FullFile;
        end

        % Value changed function: SelectedFile
        function SelectedFileValueChanged(app, event)
            app.FullFile = string(app.SelectedFile.Value);
        end

        % Button pushed function: ExtractMeshInfBtn
        function ExtractMeshInfBtnPushed(app, event)
            if(app.FullFile ~= "")
                try 
                    fopen(app.FullFile);
                    app.MeshInf = MeshInfExtract(app.FullFile);
%                     msgbox("\fontsize{13} 网格信息提取成功！",'提示信息','help', app.msgbox_style);
                    app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "网格信息提取成功！");
                    app.NumOfN.Tooltip = "1~"+string( length(app.MeshInf.coord(:, 1)) );
                    app.NumOfL.Tooltip = "1~"+string( length(app.MeshInf.element_L(:, 1)) );
                    app.NumOfS.Tooltip = "1~"+string( length(app.MeshInf.element_S(:, 1)) );
                    app.IdOfLGroup.Tooltip = "1~"+string( app.MeshInf.element_L(end, 1)-app.MeshInf.element_L(1, 1)+1 );
                    app.IdOfSGroup.Tooltip = "1~"+string( app.MeshInf.element_S(end, 1)-app.MeshInf.element_S(1, 1)+1 );
                catch
%                     msgbox("\fontsize{13} 文件路径不正确或该文件不存在！",'错误信息','error', app.msgbox_style);
                    app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "文件路径不正确或该文件不存在！");
                end
            else
%                 msgbox("\fontsize{13} 请输入文件路径！",'警告信息','warn', app.msgbox_style);
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "请输入文件路径！");
            end 
        end

        % Menu selected function: OpenFileMenu
        function OpenFileMenuMenuSelected(app, event)
            [app.file,app.path] = uigetfile('*.msh');
            app.FullFile = fullfile(app.path, app.file);
            app.SelectedFile.Value = app.FullFile;
        end

        % Button pushed function: ShowMeshBtn
        function ShowMeshBtnPushed(app, event)
            try
                ShowMesh(app.MeshInf, app, "all");
            catch
%                 msgbox("\fontsize{13} 网格显示失败！",'错误信息','error', app.msgbox_style);
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "网格显示失败！");
            end  
        end

        % Button pushed function: OutputMeshInfBtn
        function OutputMeshInfBtnPushed(app, event)
            if(app.FullFile ~= "")
                try 
                    fopen(app.FullFile);
                    output_path = uigetdir();
                    MeshInf = app.MeshInf;
                    save(output_path+"\meshInf.mat", '-struct', 'MeshInf');
%                     msgbox("\fontsize{13} 网格信息输出成功！",'提示信息','help', app.msgbox_style);
                    app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "网格信息输出成功！");
                catch
%                     msgbox("\fontsize{13} 文件路径不正确或该文件不存在！",'错误信息','error', app.msgbox_style);
                    app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "文件路径不正确或该文件不存在！");
                end
            else
%                 msgbox("\fontsize{13} 请输入文件路径！",'警告信息','warn', app.msgbox_style);
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "请输入文件路径！");
            end 
        end

        % Menu selected function: ShowMeshMenu
        function ShowMeshMenuSelected(app, event)
            try
                ShowMesh(app.MeshInf, app);
            catch
%                 msgbox("\fontsize{13} 网格显示失败！",'错误信息','error', app.msgbox_style);
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "网格显示失败！");
            end    
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            cla(app.UIAxes);
            try
                ShowMesh(app.MeshInf, app, "all");
            catch
%                 msgbox("\fontsize{13} 网格显示失败！",'错误信息','error', app.msgbox_style);
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "网格显示失败！");
            end  
        end

        % Button pushed function: ShowSelectedMesh
        function ShowSelectedMeshButtonPushed(app, event)
            cla(app.UIAxes);
            try
                ShowMesh(app.MeshInf, app, "all");
            catch
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "网格显示失败！");
            end
            try
                ShowMesh(app.MeshInf, app, "faces");
            catch
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "所选面单元不在范围内！");
            end  
            try
                ShowMesh(app.MeshInf, app, "lines");
            catch
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "所选线单元不在范围内！");
            end
            try
                ShowMesh(app.MeshInf, app, "nodes");
            catch
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "所选点单元不在范围内！");
            end  
        end

        % Button pushed function: ShowSelectedMesh_3
        function ShowSelectedMesh_3ButtonPushed(app, event)
            cla(app.UIAxes);
            try
                ShowMesh(app.MeshInf, app, "all");
            catch
%                 msgbox("\fontsize{13} 网格显示失败！",'错误信息','error', app.msgbox_style);
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "网格显示失败！");
            end  
        end

        % Button pushed function: ShowSelectedMeshGroup
        function ShowSelectedMeshGroupButtonPushed(app, event)
            cla(app.UIAxes);
            try
                ShowMesh(app.MeshInf, app, "all");
            catch
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "网格显示失败！");
            end
            try
                ShowMesh(app.MeshInf, app, "facegroups");
            catch
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "所选面单元不在范围内！");
            end  
            try
                ShowMesh(app.MeshInf, app, "linegroups");
            catch
                app.OutputInfText.Value = OutputInf(app.OutputInfText.Value, "所选线单元不在范围内！");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeshInfUIFigure = uifigure('Visible', 'off');
            app.MeshInfUIFigure.Name = 'MeshInf';
            app.MeshInfUIFigure.Icon = 'logo.png';
            app.FilesMenu = uimenu(app.MeshInfUIFigure);
            app.FilesMenu.Text = '文件';
            app.OpenFileMenu = uimenu(app.FilesMenu);
            app.OpenFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuMenuSelected, true);
            app.OpenFileMenu.Text = '打开文件';
            app.ViewMenu = uimenu(app.MeshInfUIFigure);
            app.ViewMenu.Text = '查看';
            app.ShowMeshMenu = uimenu(app.ViewMenu);
            app.ShowMeshMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowMeshMenuSelected, true);
            app.ShowMeshMenu.Text = '显示网格';
            app.MainPage = uipanel(app.MeshInfUIFigure);
            app.GridLayout = uigridlayout(app.MainPage);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '所选文件';
            app.SelectedFile = uitextarea(app.GridLayout2);
            app.SelectedFile.ValueChangedFcn = createCallbackFcn(app, @SelectedFileValueChanged, true);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '操作';
            app.GridLayout4 = uigridlayout(app.Tab);
            app.OpenFileBtn = uibutton(app.GridLayout4, 'push');
            app.OpenFileBtn.ButtonPushedFcn = createCallbackFcn(app, @OpenFileBtnPushed, true);
            app.OpenFileBtn.Text = '打开文件';
            app.ExtractMeshInfBtn = uibutton(app.GridLayout4, 'push');
            app.ExtractMeshInfBtn.ButtonPushedFcn = createCallbackFcn(app, @ExtractMeshInfBtnPushed, true);
            app.ExtractMeshInfBtn.Text = '提取网格信息';
            app.ShowMeshBtn = uibutton(app.GridLayout4, 'push');
            app.ShowMeshBtn.ButtonPushedFcn = createCallbackFcn(app, @ShowMeshBtnPushed, true);
            app.ShowMeshBtn.Text = '显示网格';
            app.OutputMeshInfBtn = uibutton(app.GridLayout4, 'push');
            app.OutputMeshInfBtn.ButtonPushedFcn = createCallbackFcn(app, @OutputMeshInfBtnPushed, true);
            app.OutputMeshInfBtn.Text = '输出网格信息';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '单元统计';
            app.GridLayout7 = uigridlayout(app.Tab_3);
            app.NumOfN = uieditfield(app.GridLayout7, 'text');
            app.NumOfN.Tooltip = {''};
            app.NumOfL = uieditfield(app.GridLayout7, 'text');
            app.NumOfS = uieditfield(app.GridLayout7, 'text');
            app.Label_2 = uilabel(app.GridLayout7);
            app.Label_2.Text = '结点';
            app.Label_3 = uilabel(app.GridLayout7);
            app.Label_3.Text = '线单元';
            app.Label_4 = uilabel(app.GridLayout7);
            app.Label_4.Text = '面单元';
            app.Button_2 = uibutton(app.GridLayout7, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '清除显示';
            app.ShowSelectedMesh = uibutton(app.GridLayout7, 'push');
            app.ShowSelectedMesh.ButtonPushedFcn = createCallbackFcn(app, @ShowSelectedMeshButtonPushed, true);
            app.ShowSelectedMesh.Text = '显示';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '单元组统计';
            app.GridLayout8 = uigridlayout(app.Tab_5);
            app.Label_6 = uilabel(app.GridLayout8);
            app.Label_6.Text = '线单元组';
            app.IdOfLGroup = uieditfield(app.GridLayout8, 'text');
            app.Label_7 = uilabel(app.GridLayout8);
            app.Label_7.Text = '面单元组';
            app.IdOfSGroup = uieditfield(app.GridLayout8, 'text');
            app.ShowSelectedMeshGroup = uibutton(app.GridLayout8, 'push');
            app.ShowSelectedMeshGroup.ButtonPushedFcn = createCallbackFcn(app, @ShowSelectedMeshGroupButtonPushed, true);
            app.ShowSelectedMeshGroup.Text = '显示';
            app.ShowSelectedMesh_3 = uibutton(app.GridLayout8, 'push');
            app.ShowSelectedMesh_3.ButtonPushedFcn = createCallbackFcn(app, @ShowSelectedMesh_3ButtonPushed, true);
            app.ShowSelectedMesh_3.Text = '清除显示';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.UIAxes = uiaxes(app.GridLayout5);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TabGroup2 = uitabgroup(app.GridLayout5);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '输出信息';
            app.GridLayout6 = uigridlayout(app.Tab_2);
            app.OutputInfText = uitextarea(app.GridLayout6);
            app.OutputInfText.Editable = 'off';
            % Show the figure after all components are created
            app.MeshInfUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GmshGridParsing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeshInfUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeshInfUIFigure)
        end
    end
end